classdef DataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField                      matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        ChannelsPanel                  matlab.ui.container.Panel
        chanOn_8                       matlab.ui.control.Switch
        chanOn_7                       matlab.ui.control.Switch
        chanOn_6                       matlab.ui.control.Switch
        chanOn_5                       matlab.ui.control.Switch
        chanOn_4                       matlab.ui.control.Switch
        chanOn_3                       matlab.ui.control.Switch
        chanOn_2                       matlab.ui.control.Switch
        chanOn_1                       matlab.ui.control.Switch
        chanName_1                     matlab.ui.control.EditField
        EnableLabel                    matlab.ui.control.Label
        ChannelNameLabel               matlab.ui.control.Label
        chanName_8                     matlab.ui.control.EditField
        chanName_7                     matlab.ui.control.EditField
        chanName_6                     matlab.ui.control.EditField
        chanName_5                     matlab.ui.control.EditField
        chanName_4                     matlab.ui.control.EditField
        chanName_3                     matlab.ui.control.EditField
        chanName_2                     matlab.ui.control.EditField
        RateTimeControlsPanel          matlab.ui.container.Panel
        RecordTimesEditField           matlab.ui.control.NumericEditField
        RecordTimesEditFieldLabel      matlab.ui.control.Label
        PlotRateHzEditField            matlab.ui.control.NumericEditField
        PlotRateHzEditFieldLabel       matlab.ui.control.Label
        DAQRateHzEditField             matlab.ui.control.NumericEditField
        DAQRateHzEditFieldLabel        matlab.ui.control.Label
        DAQLoggingControlsPanel        matlab.ui.container.Panel
        ArmDataAcquisitionSwitch       matlab.ui.control.Switch
        ArmDataAcquisitionSwitchLabel  matlab.ui.control.Label
        TriggerButton                  matlab.ui.control.StateButton
        FileSavingParametersPanel      matlab.ui.container.Panel
        FolderNote                     matlab.ui.control.Label
        FolderButton                   matlab.ui.control.Button
        ProjectFolderEditField         matlab.ui.control.EditField
        ProjectFolderEditFieldLabel    matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        mainPlot                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        s1      % DAQ session 
        data    % Input data matrix
        name    % Filenames and folders
        channels % Input channel info (names of on switch elements, state of which are activated, etc)
        saveForm% fprintf save format, must update if new inputs added
        rate    % All relevant information for timing/rates
        plotTimer
        saveTimer
        lh1_in  % Listener for input
        newPanels
    end

    methods (Access = private)
    
        function GrabData1(app,event,info)
            %Append matrices with new data
            app.data.time = [app.data.time;info.TimeStamps];
            app.data.data = [app.data.data;info.Data];
            % trim to rolling buffer size
            if length(app.data.time) >= app.rate.buffer
                app.data.time = app.data.time(end-app.rate.buffer+1:end);
                app.data.data = app.data.data(end-app.rate.buffer+1:end,:);
            end
        end
        
    
        function plotTimerUpdateFcn(app,src,event)
            %Update Plots
            if ~isempty(app.data.time)
                plot(app.mainPlot, app.data.time, app.data.data)
            end
        end
    

        function getChannelActiveState(app)
            % Get on switches by finding fields with "chanOn" in name
            fd = fieldnames(app);
            onswitches = cellfun(@(x) ~isempty(x), strfind(fd, 'chanOn'));
            is_onswitch = find(onswitches);
            % Get value of all switches, save to struct
            values = arrayfun(@(x) app.(fd{x}).Value, is_onswitch, 'UniformOutput', false);
            app.channels.active = ismember(values, 'On');
        end


        function stopRecordingReset(app)
            %Delete listeners
            delete(app.lh1_in)
            %Stop sessions
            stop(app.s1)
            %Stop Timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            if strcmp(get(app.saveTimer,'Running'),'on')
                stop(app.saveTimer)
            end
            % Write data to file
            save(app.name.filename, app.data, app.name.chanNames)
            
            %Reset GUI and System
            app.ArmDataAcquisitionSwitch.Value = '';
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.ArmDataAcquisitionSwitch.FontWeight = 'normal';
            app.ArmDataAcquisitionSwitch.FontColor = [0,0,0];
            app.TriggerButton.Enable = 'Off';
            app.TriggerButton.Value = false;
            app.ProjectFolderEditField.Enable = 'On';
            app.FilenameEditField.Enable = 'On';
            app.FolderButton.Enable = 'On';
            app.DAQRateHzEditField.Enable = 'On';
            app.PlotRateHzEditField.Enable = 'On';
            app.RecordTimesEditField.Enable = 'On';
%             for i = 1:app.channels.howmany
%                 app.(app.channels)
            app.chanOn_1.Enable = 'On';
            app.chanOn_2.Enable = 'On';
            app.chanOn_3.Enable = 'On';
            app.chanOn_4.Enable = 'On';
            app.chanOn_5.Enable = 'On';
            app.chanOn_6.Enable = 'On';
            app.chanOn_7.Enable = 'On';
            app.chanOn_8.Enable = 'On';
            %Clear out, re-declare matrices and indices
            app.data.time = [];
            app.data.data = [];
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get handles for all channel on switches (useful to reduce copypaste)
            fd = fieldnames(app);
            onSwitches = find(cellfun(@(x) ~isempty(x), strfind(fd, 'chanOn')));
            app.channels.switchHandles = arrayfun(@(x) fd{x}, onSwitches, 'UniformOutput', false);
            % Get handles for channel names
            chanNames = find(cellfun(@(x) ~isempty(x), strfind(fd, 'chanName')));
            app.channels.nameHandles = arrayfun(@(x) fd{x}, chanNames, 'UniformOutput', false);
            app.channels.howMany = length(onSwitches);
            
        end

        % Button pushed function: FolderButton
        function FolderSelect(app, event)
            % Let the user select the folder to store data
            dialogTitle = 'Select directory for saving data';
            folderName = uigetdir(pwd,dialogTitle);
            app.ProjectFolderEditField.Value = folderName;
            % Enable DAQ arming, remove note
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.FolderNote.Visible = 'Off';
        end

        % Value changed function: TriggerButton
        function StartLoggingSession(app, event)
            value = app.TriggerButton.Value;
            if value
                %Disable file selectors, BeginLoggingButton on GUI
                app.TriggerButton.Enable = 'Off';
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                % Reset GUI, save data (if any), and stop data recording
                stopRecordingReset(app)
            end
        end

        % Value changed function: ArmDataAcquisitionSwitch
        function ArmDataAcquisitionSwitchValueChanged(app, event)
            value = app.ArmDataAcquisitionSwitch.Value;
            
            %Actions to do when data acquisition has been armed
            if strcmp(value,'Armed')
                % Start up and preallocate DAQ 
%                 daqreset;
                %Declare Rates 
                app.rate.DAQ = app.DAQRateHzEditField.Value; %(Hz)
                app.rate.plot = app.PlotRateHzEditField.Value; %(Hz)
                app.rate.plotLength = app.RecordTimesEditField.Value; %(s)
                app.rate.buffer = app.rate.plotLength * app.rate.DAQ; %(samples)
                %Predeclare data
                app.data.time = [];
                app.data.data = [];
                % Get which channels are active
                getChannelActiveState(app)
                
                % Change looks of data acquisition switch
                app.ArmDataAcquisitionSwitch.FontWeight = 'bold';
                app.ArmDataAcquisitionSwitch.FontColor = [0.635,0.078,0.184];
                
                %Make folder in chosen directory for today's date (if not already there)
                app.name.projFolder = app.ProjectFolderEditField.Value;
                app.name.path = strcat(app.name.projFolder, filesep, datestr(now,'yyyy_mm_dd'));
                if ~exist(app.name.path)
                    mkdir(app.name.projFolder,datestr(now,'yyyy_mm_dd'))
                end

                %Initialize filename of this test
                if ~isempty(app.FilenameEditField.Value)
                    app.name.filename = strcat(app.FilenameEditField.Value, '.mat');
                else
                    app.name.filename = strcat('testWithNoName', datestr(now,'mm_dd_yy_HH_MM_SS'),'.mat');
                end
                % Increment filename with numbers if same file already exists
                if exist(fullfile(app.name.path, app.name.filename))
                    % Add number if no number (dumb hard-indexed method)
                    if app.name.filename(end-5)~='_'
                        app.name.filename = strcat(app.name.filename(1:end-4), '_1', app.name.filename(end-3:end));
                        app.FilenameEditField.Value = app.name.filename(1:end-4);
                    % Otherwise grab and increment number
                    else
                        number = str2double(app.name.filename(end-4));
                        app.name.filename = strcat(app.name.filename(1:end-5), num2str(number+1), app.name.filename(end-3:end));
                        app.FilenameEditField.Value = app.name.filename(1:end-4);
                    end
                end
                
                % Get column names of active channels, add delimiter between
                app.name.chanNames = cell(1, app.channels.howMany);
                for i = 1:app.channels.howMany
                    app.name.chanNames{i} = app.(app.channels.nameHandles{i}).Value;
                end
                app.name.chanNames = [{'time'}, app.name.chanNames(app.channels.active)];
                

                %CREATE DAQ SESSION 1 (Voltage in)
                app.s1 = daq('ni');
                app.rate.DAQ = app.DAQRateHzEditField.Value;
                app.s1.Rate = app.rate.DAQ;
                % Create channels
                % TODO: With actual DAQ set up new system. Hard to say if
                % it'll allow s1.addAnalogInputChannel as opposed to addAnalogInputChannel(s1, ...)
                % https://www.mathworks.com/help/daq/transition-your-code-from-session-to-dataacquisition-interface.html
                app.s1.addAnalogInputChannel('Dev1', [find(app.channels.active)-1], 'Voltage');
                %Create input/output listeners
                app.lh1_in = app.s1.addlistener('DataAvailable',@app.GrabData1);                
                
                %Create Plotting Timer
                app.rate.plot = app.PlotRateHzEditField.Value;
                app.plotTimer = timer('TimerFcn',{@app.plotTimerUpdateFcn},...
                    'ExecutionMode','fixedRate','busymode','queue',...
                    'Period',1/app.rate.plot,'Name','timer-1');
                start(app.plotTimer);
                
                % Disable fields that shouldn't change during recording
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                app.DAQRateHzEditField.Enable = 'Off';
                app.PlotRateHzEditField.Enable = 'Off';
                app.RecordTimesEditField.Enable = 'Off';
                for i = 1:app.channels.howMany
                    app.(app.channels.switchHandles{i}).Enable = 'Off';
                end
                
                % Start main session
                startBackground(app.s1)
           
            % Actions to do when switch is disarmed
            else
                stopRecordingReset(app)
            end

            % Enable data logging if data acq. has been armed and folder specified
            if ~isempty(app.ProjectFolderEditField.Value) && strcmp(value,'Armed')
                app.TriggerButton.Enable = 'On';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Stop sessions, catch errors in case they didn't exist or were already killed
            try
                stop(app.s1)
            catch
            end
            %Delete Listeners
            delete(app.lh1_in)
            %Stop timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            if strcmp(get(app.saveTimer,'Running'),'on')
                stop(app.saveTimer)
            end
            % Close files
            fclose('all');
            
            delete(app)
        end

        % Value changed function: EditField
        function InputPanel(app, event)
            value = app.EditField.Value;
            app.newPanels.newChanPan = uipanel(app.UIFigure);
            app.newPanels.newChanPan.Title = 'Testme';
            app.newPanels.newChanPan.Position = [761,202-100*value,163,90];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.mainPlot = uiaxes(app.UIFigure);
            xlabel(app.mainPlot, 'Time (s)')
            ylabel(app.mainPlot, 'Voltage')
            app.FileSavingParametersPanel = uipanel(app.UIFigure);
            app.FileSavingParametersPanel.Title = 'File Saving Parameters';
            app.FilenameEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.ProjectFolderEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.FolderButton = uibutton(app.FileSavingParametersPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelect, true);
            app.FolderButton.Text = '...';
            app.FolderNote = uilabel(app.FileSavingParametersPanel);
            app.FolderNote.Text = 'Note: Project Folder must be selected to start acquiring data!';
            app.DAQLoggingControlsPanel = uipanel(app.UIFigure);
            app.DAQLoggingControlsPanel.Title = 'DAQ/Logging Controls';
            app.TriggerButton = uibutton(app.DAQLoggingControlsPanel, 'state');
            app.TriggerButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingSession, true);
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.ArmDataAcquisitionSwitchLabel = uilabel(app.DAQLoggingControlsPanel);
            app.ArmDataAcquisitionSwitchLabel.Text = 'Arm Data Acquisition';
            app.ArmDataAcquisitionSwitch = uiswitch(app.DAQLoggingControlsPanel, 'slider');
            app.ArmDataAcquisitionSwitch.Items = {'', 'Armed'};
            app.ArmDataAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmDataAcquisitionSwitchValueChanged, true);
            app.ArmDataAcquisitionSwitch.Enable = 'off';
            app.ArmDataAcquisitionSwitch.Value = '';
            app.RateTimeControlsPanel = uipanel(app.UIFigure);
            app.RateTimeControlsPanel.Title = 'Rate/Time Controls';
            app.DAQRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.DAQRateHzEditFieldLabel.Text = 'DAQ Rate (Hz)';
            app.DAQRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.DAQRateHzEditField.Value = 10000;
            app.PlotRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.PlotRateHzEditFieldLabel.Text = 'Plot Rate (Hz)';
            app.PlotRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.PlotRateHzEditField.Value = 2;
            app.RecordTimesEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.RecordTimesEditFieldLabel.Text = 'Record Time (s)';
            app.RecordTimesEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.RecordTimesEditField.Value = 20;
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.chanName_2 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_2.Value = 'ch1';
            app.chanName_3 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_3.Value = 'ch2';
            app.chanName_4 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_4.Value = 'ch3';
            app.chanName_5 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_5.Value = 'ch4';
            app.chanName_6 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_6.Value = 'ch5';
            app.chanName_7 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_7.Value = 'ch6';
            app.chanName_8 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_8.Value = 'ch7';
            app.ChannelNameLabel = uilabel(app.ChannelsPanel);
            app.ChannelNameLabel.Text = 'Channel Name';
            app.EnableLabel = uilabel(app.ChannelsPanel);
            app.EnableLabel.Text = 'Enable';
            app.chanName_1 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_1.Value = 'ch0';
            app.chanOn_1 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_1.Value = 'On';
            app.chanOn_2 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_2.Value = 'On';
            app.chanOn_3 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_3.Value = 'On';
            app.chanOn_4 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_4.Value = 'On';
            app.chanOn_5 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_5.Value = 'On';
            app.chanOn_6 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_6.Value = 'On';
            app.chanOn_7 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_7.Value = 'On';
            app.chanOn_8 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_8.Value = 'On';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @InputPanel, true);
            app.EditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end